classdef SimulacionSangre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Button                          matlab.ui.control.Button
        AnchoplacamEditField            matlab.ui.control.EditField
        AnchoplacamEditFieldLabel       matlab.ui.control.Label
        LongitudplacamEditField_2       matlab.ui.control.EditField
        LongitudplacamEditField_2Label  matlab.ui.control.Label
        AnchoplacamEditField_2          matlab.ui.control.NumericEditField
        AnchoplacamEditField_2Label     matlab.ui.control.Label
        ValordehEditField               matlab.ui.control.EditField
        ValordehEditFieldLabel          matlab.ui.control.Label
        PosicinenyfinalLabel            matlab.ui.control.Label
        PosicinenxfinalLabel            matlab.ui.control.Label
        PosicinenyEditField             matlab.ui.control.EditField
        PosicinenyEditFieldLabel        matlab.ui.control.Label
        PosicinenxEditField             matlab.ui.control.EditField
        PosicinenxEditFieldLabel        matlab.ui.control.Label
        VelocidaddedisparomsEditField   matlab.ui.control.EditField
        VelocidaddedisparomsEditFieldLabel  matlab.ui.control.Label
        MasadesangregEditField          matlab.ui.control.EditField
        MasadesangregEditFieldLabel     matlab.ui.control.Label
        CargadesangreCEditField         matlab.ui.control.EditField
        CargadesangreCEditFieldLabel    matlab.ui.control.Label
        DistanciaentreplacasmEditField  matlab.ui.control.EditField
        DistanciaentreplacasmEditFieldLabel  matlab.ui.control.Label
        CargadelasplacasCEditField      matlab.ui.control.NumericEditField
        CargadelasplacasCEditFieldLabel  matlab.ui.control.Label
        LongitudplacamEditField         matlab.ui.control.NumericEditField
        LongitudplacamEditFieldLabel    matlab.ui.control.Label
        LimpiarButton                   matlab.ui.control.Button
        GraficarButton                  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        MagnitudLabel                   matlab.ui.control.Label
        DireccinLabel                   matlab.ui.control.Label
        PosicinLabel                    matlab.ui.control.Label
        CampoElctricoLabel              matlab.ui.control.Label
        yLabel                          matlab.ui.control.Label
        xLabel                          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            
% declaración de constantes
Q = app.CargadelasplacasCEditField.Value;
Qg = app.CargadesangreCEditField.Value;
Xi = app.PosicinenxEditField.Value;
Yi = app.PosicinenyEditField.Value;
sep = char(app.ValordehEditField.Value);
dis = app.DistanciaentreplacasmEditField.Value;
longP = char(app.LongitudplacamEditField.Value);
anchoP = char(app.AnchoplacamEditField_2.Value);
longN = char(app.LongitudplacamEditField_2.Value);
anchoN = char(app.AnchoplacamEditField.Value);
vel = app.VelocidaddedisparomsEditField.Value;

ejes = app.UIAxes;
xlim = ([-10 10]);
ylim = ([-10 10]);
hold(ejes,"on");

[xe, ye] = meshgrid(-8:0.4:8);
U = zeros(size(xe));
V = zeros(size(ye));

% declaración de cargas
qP = Q;
qN = -Q;

% cálculo de diferencial de carga
sigma = 4;

dAp = (longP .* anchoP);
dAn = (longN .* anchoN);

dQp = dAp * sigma;
dQn = dAn * sigma;

% definición de las coordenadas para las láminas

nY = char(Yi):sep:char(Yi+longP);
nY2 = char(Yi):sep:char(Yi+longN);

mX = str2num(Xi).*ones(size(nY));
mX2 = (Xi+dis).*ones(size(nY2));

if longP > longN
    mtx = mX2;
else
    mtx = mX;
end

% cálculo y suma de los campos eléctricos

for i = 1:size(mtx, 2)
    
    K = 8.99 * 10^9;

    Rx = xe - mX(i);
    Ry = ye - nY(i);

    R = sqrt(Rx.^2 + Ry.^2).^3;
    
    Ex = K .* qN .* Rx ./ R;
    Ey = K .* qN .* Ry ./ R;

    Rx = xe - mX2(i);
    Ry = ye - nY2(i);

    R = sqrt(Rx.^2 + Ry.^2).^3;

    Ex = Ex + K.*qP.*Rx./R;
    Ey = Ey + K.*qP.*Ry./R;

    E = sqrt(Ex.^2 + Ey.^2);

    U = U + Ex./E;
    V = V + Ey./E;

end

% gráficos

g = quiver(ejes,xe, ye, U, V, 'AutoScaleFactor', 0.6);
set(g, 'color', [1 0 0], 'LineWidth', 1.2);
%plot(ejes,mX, nY, 'ob', 'LineWidth',5);
%plot(ejes,mX2, nY2, 'or', 'LineWidth',5);

% globulos

Ti = 0;
deltat = 0.01;
x0 = 0;
y0 = 4;
v0 = 0;
tiempo = 100;
g = 9.8; 

vx = [0]; 
vy = [-10]; 

tiempo1 = [Ti]; 
tiempo2 = [Ti]; 
 
px = [x0];  
py = [y0]; 

iteraciones = tiempo/deltat;

masa = (app.MasadesangregEditField.Value)*100;

ax = [-(E(x0 + 1, y0 + 1) * Qg)./masa];
ay = [(- (masa * g)-(E(x0 + 1, y0 + 1) * Qg)) ./ masa]; 

for i = 2:(iteraciones+1) 
    vy = [vy, vy(i-1)+deltat*ay(i-1)];
    vx = [vx, vx(i-1)+deltat*ax(i-1)];

    py = [py, py(i-1)+vy(i-1)*deltat+(1/2)*ay(i-1)*(deltat^2)];
    px = [px, px(i-1)+vx(i-1)*deltat+(1/2)*ax(i-1)*(deltat^2)];

    if py(i) > 10 || py(i) < -10
         app.PosicinenxfinalLabel.Text = ("Posición en x final: " + num2str(px(i)));
         app.PosicinenyfinalLabel.Text = ("Posición en y final: " + num2str(py(i)));
        break
    elseif px(i) > 100 || px(i) < -10
         app.PosicinenxfinalLabel.Text = ("Posición en x final: " + num2str(px(i)));
         app.PosicinenyfinalLabel.Text = ("Posición en y final: " + num2str(py(i)));
        break
    end

    Xidx = round(abs(px(i-1)));
    Yidx = round(abs(py(i-1)));

    ax = [ax, ax + -(E(Xidx + 1, Yidx + 1) * Qg)./masa];
    ay = [ay, ay + ((masa * g)-(E(Xidx + 1, Yidx + 1) * Qg)) ./ masa];

    plot(ejes,px(i),py(i),'-o','MarkerIndices',1:5:length(py))
    app.MagnitudLabel.Text = ("Magnitud = " + string(py(i)));
    app.DireccinLabel.Text = ("Dirección = " + string(px(i)));
    app.yLabel.Text = ("y = " + string(py(i)));
    app.xLabel.Text = ("x = " + string(px(i)));
    drawnow
end 


        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {519, 519};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {283, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GraficarButton = uibutton(app.LeftPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.LimpiarButton = uibutton(app.LeftPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.LongitudplacamEditFieldLabel = uilabel(app.LeftPanel);
            app.LongitudplacamEditFieldLabel.Text = 'Longitud placa + (m)';
            app.LongitudplacamEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LongitudplacamEditField.Value = 6;
            app.CargadelasplacasCEditFieldLabel = uilabel(app.LeftPanel);
            app.CargadelasplacasCEditFieldLabel.Text = 'Carga de las placas (C)';
            app.CargadelasplacasCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CargadelasplacasCEditField.Value = 20;
            app.DistanciaentreplacasmEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanciaentreplacasmEditFieldLabel.Text = 'Distancia entre placas (m)';
            app.DistanciaentreplacasmEditField = uieditfield(app.LeftPanel, 'text');
            app.DistanciaentreplacasmEditField.Value = '10';
            app.CargadesangreCEditFieldLabel = uilabel(app.LeftPanel);
            app.CargadesangreCEditFieldLabel.Text = 'Carga de sangre (C)';
            app.CargadesangreCEditField = uieditfield(app.LeftPanel, 'text');
            app.CargadesangreCEditField.Value = '5';
            app.MasadesangregEditFieldLabel = uilabel(app.LeftPanel);
            app.MasadesangregEditFieldLabel.Text = 'Masa de sangre (g)';
            app.MasadesangregEditField = uieditfield(app.LeftPanel, 'text');
            app.MasadesangregEditField.Value = '100';
            app.VelocidaddedisparomsEditFieldLabel = uilabel(app.LeftPanel);
            app.VelocidaddedisparomsEditFieldLabel.Text = 'Velocidad de disparo (m/s)';
            app.VelocidaddedisparomsEditField = uieditfield(app.LeftPanel, 'text');
            app.VelocidaddedisparomsEditField.Value = '10';
            app.PosicinenxEditFieldLabel = uilabel(app.LeftPanel);
            app.PosicinenxEditFieldLabel.Text = 'Posición en x';
            app.PosicinenxEditField = uieditfield(app.LeftPanel, 'text');
            app.PosicinenxEditField.Value = '-4';
            app.PosicinenyEditFieldLabel = uilabel(app.LeftPanel);
            app.PosicinenyEditFieldLabel.Text = 'Posición en y';
            app.PosicinenyEditField = uieditfield(app.LeftPanel, 'text');
            app.PosicinenyEditField.Value = '-2';
            app.PosicinenxfinalLabel = uilabel(app.LeftPanel);
            app.PosicinenxfinalLabel.Text = 'Posición en x final: ';
            app.PosicinenyfinalLabel = uilabel(app.LeftPanel);
            app.PosicinenyfinalLabel.Text = 'Posición en y final: ';
            app.ValordehEditFieldLabel = uilabel(app.LeftPanel);
            app.ValordehEditFieldLabel.Text = 'Valor de h';
            app.ValordehEditField = uieditfield(app.LeftPanel, 'text');
            app.ValordehEditField.Value = '0.1';
            app.AnchoplacamEditField_2Label = uilabel(app.LeftPanel);
            app.AnchoplacamEditField_2Label.Text = 'Ancho placa + (m)';
            app.AnchoplacamEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.AnchoplacamEditField_2.Value = 2;
            app.LongitudplacamEditField_2Label = uilabel(app.LeftPanel);
            app.LongitudplacamEditField_2Label.Text = 'Longitud placa - (m)';
            app.LongitudplacamEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.LongitudplacamEditField_2.Value = '6';
            app.AnchoplacamEditFieldLabel = uilabel(app.LeftPanel);
            app.AnchoplacamEditFieldLabel.Text = 'Ancho placa - (m)';
            app.AnchoplacamEditField = uieditfield(app.LeftPanel, 'text');
            app.AnchoplacamEditField.Value = '2';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Trayectoria de Partícula')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.xLabel = uilabel(app.RightPanel);
            app.xLabel.Text = 'x = ';
            app.yLabel = uilabel(app.RightPanel);
            app.yLabel.Text = 'y = ';
            app.CampoElctricoLabel = uilabel(app.RightPanel);
            app.CampoElctricoLabel.Text = 'Campo Eléctrico: ';
            app.PosicinLabel = uilabel(app.RightPanel);
            app.PosicinLabel.Text = 'Posición: ';
            app.DireccinLabel = uilabel(app.RightPanel);
            app.DireccinLabel.Text = 'Dirección = ';
            app.MagnitudLabel = uilabel(app.RightPanel);
            app.MagnitudLabel.Text = 'Magnitud = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimulacionSangre

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end